classdef BTE_and_SURC_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BedTempertureEntryBTETab        matlab.ui.container.Tab
        ShowTminandTmaxCheckBox         matlab.ui.control.CheckBox
        ShowTaupointCheckBox            matlab.ui.control.CheckBox
        text1_19                        matlab.ui.control.Label
        text4_12                        matlab.ui.control.Label
        text2_10                        matlab.ui.control.Label
        text1_18                        matlab.ui.control.Label
        text1_17                        matlab.ui.control.Label
        text1_16                        matlab.ui.control.Label
        text1_15                        matlab.ui.control.Label
        OtherCalculatedParametersPanel  matlab.ui.container.Panel
        text01_4                        matlab.ui.control.Label
        text01_3                        matlab.ui.control.Label
        umsEditField                    matlab.ui.control.NumericEditField
        umsEditFieldLabel               matlab.ui.control.Label
        FillLevelEditField              matlab.ui.control.NumericEditField
        FillLevelLabel                  matlab.ui.control.Label
        FillAngleEditField              matlab.ui.control.NumericEditField
        FillAngleEditFieldLabel         matlab.ui.control.Label
        tEditField_2                    matlab.ui.control.NumericEditField
        tEditField_2Label               matlab.ui.control.Label
        ApmmEditField                   matlab.ui.control.NumericEditField
        ApmmLabel                       matlab.ui.control.Label
        AmEditField                     matlab.ui.control.NumericEditField
        AmLabel                         matlab.ui.control.Label
        Tau_pEditField                  matlab.ui.control.NumericEditField
        ParticleDiametermmEditField     matlab.ui.control.NumericEditField
        ParticleDiametermmEditFieldLabel  matlab.ui.control.Label
        BulkDensitykgmEditField         matlab.ui.control.NumericEditField
        DensitykgmLabel                 matlab.ui.control.Label
        TimeatPositionEditField         matlab.ui.control.NumericEditField
        TimeatPositionEditFieldLabel    matlab.ui.control.Label
        PositioninTubemEditField        matlab.ui.control.NumericEditField
        PositioninTubemEditFieldLabel   matlab.ui.control.Label
        AngleofReposeEditField          matlab.ui.control.NumericEditField
        AngleofReposeEditFieldLabel     matlab.ui.control.Label
        FeedRatekghrEditField           matlab.ui.control.NumericEditField
        FeedRatekghrEditFieldLabel      matlab.ui.control.Label
        DrumDiametermEditField          matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        DrumDiametermEditFieldLabel     matlab.ui.control.Label
        tEditField                      matlab.ui.control.NumericEditField
        AngleofInclinedegEditField      matlab.ui.control.NumericEditField
        AngleofInclinedegEditFieldLabel  matlab.ui.control.Label
        RotationRateRPMEditField        matlab.ui.control.NumericEditField
        RotationRateRPMEditFieldLabel   matlab.ui.control.Label
        TempPositionKEditField          matlab.ui.control.NumericEditField
        TempPositionKEditFieldLabel     matlab.ui.control.Label
        WallTemperatureKEditField       matlab.ui.control.NumericEditField
        WallTemperatureKEditFieldLabel  matlab.ui.control.Label
        InitialTemperatureKEditField    matlab.ui.control.NumericEditField
        InitialTemperatureKEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        OperatingConditionsLabel        matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        MaterialPropertiesLabel         matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        ExperimentalInputLabel          matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ScaleupofRotaryCalcinerSURCTab  matlab.ui.container.Tab
        CopyValuesFromBTEButton         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        text0_10                        matlab.ui.control.Label
        text0_11                        matlab.ui.control.Label
        text0_2                         matlab.ui.control.Label
        text0                           matlab.ui.control.Label
        text01                          matlab.ui.control.Label
        text0_1                         matlab.ui.control.Label
        text0_3                         matlab.ui.control.Label
        text0_6                         matlab.ui.control.Label
        text0_7                         matlab.ui.control.Label
        text01_1                        matlab.ui.control.Label
        text01_2                        matlab.ui.control.Label
        pushbutton1                     matlab.ui.control.Button
        phi                             matlab.ui.control.EditField
        A                               matlab.ui.control.EditField
        Ap                              matlab.ui.control.EditField
        tau                             matlab.ui.control.EditField
        particle_d                      matlab.ui.control.EditField
        text0_4                         matlab.ui.control.Label
        text0_5                         matlab.ui.control.Label
        Wall_Temperature                matlab.ui.control.EditField
        Initial_Temperature             matlab.ui.control.EditField
        text0_8                         matlab.ui.control.Label
        text0_9                         matlab.ui.control.Label
        calp                            matlab.ui.control.CheckBox
        w                               matlab.ui.control.EditField
        text1                           matlab.ui.control.Label
        theta                           matlab.ui.control.EditField
        text1_1                         matlab.ui.control.Label
        text1_2                         matlab.ui.control.Label
        text1_3                         matlab.ui.control.Label
        text1_4                         matlab.ui.control.Label
        DrumDiameter                    matlab.ui.control.EditField
        calc_phi                        matlab.ui.control.CheckBox
        text1_5                         matlab.ui.control.Label
        text1_6                         matlab.ui.control.Label
        text1_7                         matlab.ui.control.Label
        density                         matlab.ui.control.EditField
        text2                           matlab.ui.control.Label
        conductivity                    matlab.ui.control.EditField
        heatCapacity                    matlab.ui.control.EditField
        text2_1                         matlab.ui.control.Label
        YoungsModulus                   matlab.ui.control.EditField
        text2_2                         matlab.ui.control.Label
        Poissons                        matlab.ui.control.EditField
        text2_3                         matlab.ui.control.Label
        text2_4                         matlab.ui.control.Label
        text1_8                         matlab.ui.control.Label
        text1_9                         matlab.ui.control.Label
        tau_c                           matlab.ui.control.EditField
        tau_p                           matlab.ui.control.EditField
        text2_5                         matlab.ui.control.Label
        text1_10                        matlab.ui.control.Label
        text1_11                        matlab.ui.control.Label
        VelocityChoice                  matlab.ui.control.DropDown
        Velocity                        matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        text2_6                         matlab.ui.control.Label
        text2_7                         matlab.ui.control.Label
        text2_8                         matlab.ui.control.Label
        text2_9                         matlab.ui.control.Label
        text1_12                        matlab.ui.control.Label
        fill_level                      matlab.ui.control.EditField
        text1_13                        matlab.ui.control.Label
        text1_14                        matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        text4_1                         matlab.ui.control.Label
        bulk_density                    matlab.ui.control.EditField
        text4_2                         matlab.ui.control.Label
        text4_3                         matlab.ui.control.Label
        text4_4                         matlab.ui.control.Label
        friction                        matlab.ui.control.EditField
        text4_5                         matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        text5_1                         matlab.ui.control.Label
        calciner_length                 matlab.ui.control.EditField
        text4_6                         matlab.ui.control.Label
        incline                         matlab.ui.control.EditField
        text4_7                         matlab.ui.control.Label
        text4_8                         matlab.ui.control.Label
        text4_9                         matlab.ui.control.Label
        feed_rate                       matlab.ui.control.EditField
        text4_10                        matlab.ui.control.Label
        text3_1                         matlab.ui.control.Label
        clear_figure                    matlab.ui.control.Button
        text4_11                        matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        Panel_7                         matlab.ui.container.Panel
        text0_12                        matlab.ui.control.Label
        text40                          matlab.ui.control.Label
        dispersion                      matlab.ui.control.EditField
        text39                          matlab.ui.control.Label
        DispersionChoice                matlab.ui.control.DropDown
        axes1                           matlab.ui.control.UIAxes
        axes2                           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function UpdateAxis(app, handles)
               global table_pop
               global figure_Ttime
               mymode = struct('WindowStyle','nonmodal','Interpreter','latex');
               check_conditions(handles);
            
               phi = get(handles.phi,'string') ;
               phi = str2double(phi);
            
               particle_d = get(handles.particle_d,'string') ;
               %d in mm
               particle_d = str2double(particle_d);
            
               if (particle_d <= 0)
                 uiwait(errordlg('$d$ must be $>$ 0. $d$ is set to 1 mm.','Value Error', mymode));
                 particle_d = 1.0;
                 set(handles.particle_d,'String',num2str(particle_d));
               end
               particle_d = particle_d/1000;
            
               Ap = particle_d^2/4*pi;
               set(handles.Ap,'String',num2str(Ap*1000000.0));
            
               A = get(handles.A,'string') ;
               A = str2double(A);
               if (A <= 0)
                 uiwait(errordlg('$A$ must be $>$ 0. $A$ is set to 1 m$^2$.','Value Error', mymode));
                 A = 1.0;
                 set(handles.A,'String',num2str(A));
               end
            
               comp_tc = get(handles.calp,'Value');
               if (comp_tc ==0 && phi <= 0)
                 uiwait(errordlg('$\phi$ must be $>$ 0. $\phi$ is set to 1.','Value Error', mymode));
                 phi = 1;
                 set(handles.phi,'String',num2str(phi));
               end
            
             if (comp_tc ==1);
                 w = get(handles.w,'string') ;
                 %w in rpm
                 w = str2double(w);
                 if (w <= 0)
                 uiwait(errordlg('$\omega$ must be $>$ 0. $\omega$ is set to 1 rpm.','Value Error', mymode));
                 w = 1.0;
                 set(handles.w,'String',num2str(w));
                 end
                 w = w*2*pi/60;
            
                 Uax = compute_velocity(handles,mymode);
            
                 theta = get(handles.theta,'string');
                 %theta in radians
                 theta = str2double(theta);
                 if (theta <= 0)
                 uiwait(errordlg('$\theta$ must be $>$ 0. $\theta$ is set to 1 degree.','Value Error', mymode));
                 theta = 1.0;
                 set(handles.theta,'String',num2str(theta));
                 end
            
                 if (theta > 180)
                 uiwait(errordlg('$\theta$ $>$ 180. Fill level greater than 50$\%$.','Value Error', mymode));
                 end
            
                 theta = theta/180.0*pi;
            
                 D = get(handles.DrumDiameter,'string') ;
                 D = str2double(D);
                 if (D <= 0)
                 uiwait(errordlg('$D$ must be $>$ 0. $D$ is set to 1 m.','Value Error', mymode));
                 D = 1.0;
                 set(handles.DrumDiameter,'String',num2str(D));
                 end
            
            
                 A = 0.5*(D/2)^2*(theta - sin(theta));
                 set(handles.A,'String',num2str(A));
            
                 tau_c = (theta/w);
                 if (tau_c <= 0)
                    uiwait(errordlg('$\tau_c$ must be $>$ 0. $\tau_c$ is set to 1.','Value Error', mymode));
                    tau_c = 1.0;
                 end
                 set(handles.tau_c,'String',num2str(tau_c));
            
                 tau_p = get(handles.tau_p,'string') ;
                 tau_p = str2double(tau_p);
                 if (tau_p <= 0)
                    uiwait(errordlg('$\tau_p$ must be $>$ 0. $\tau_p$ is set to 1.','Value Error', mymode));
                    tau_p = 1.0;
                 end
                 set(handles.tau_p,'String',num2str(tau_p));
            
                 comp_tp = get(handles.calc_phi,'Value');
                 if(comp_tp ==1)
            
                   density = get(handles.density,'string') ;
                   density = str2double(density);
            
                   k = get(handles.conductivity,'string') ;
                   k = str2double(k);
            
                   cp = get(handles.heatCapacity,'string') ;
                   cp = str2double(cp);
            
                   E = get(handles.YoungsModulus,'string') ;
                   %E in GPa
                   E = str2double(E);
                   E = E*1000000000;
            
                   nu = get(handles.Poissons,'string') ;
                   nu = str2double(nu);
            
                   %Estar = E/2/(1-nu^2);
                    %%%REMOVE LATER
                   %E = 2*E*(1+nu);
                   %Gw = Inf; %7.93e7;
            
                   %Ew = 2*Gw*(1+vw);
                   Ew = E;
                   vw = 0.30;
            
                   Estar = ((1-nu^2)/E + (1-vw^2)/Ew)^(-1);
            
                   tau_p = density^(2/3)*particle_d^(5/3)*Estar^(1/3)*cp/k;
                   konstant = (4/3*pi*(0.5)^(5/3))/(2*(pi*9.81)^(1/3));
                   tau_p = tau_p*konstant;
            
                   if (tau_p < 0 || ~isreal(tau_p))
                     uiwait(errordlg('$\tau_p$ must be $>$ 0. $\tau_p$ is set to 1.','Value Error', mymode));
                     tau_p = 1.0;
                   end
                   set(handles.tau_p,'String',num2str(tau_p));
            
                 end
            
                 phi = tau_p/tau_c;
            
                 set(handles.phi,'String',num2str(phi));
             end
            
             Uax = get(handles.Velocity,'string');
             Uax = str2double(Uax);
            
             x = 0.1:10:1000;
             tau =0.21*(A/Ap)^(2/3)*x.^(0.88);
            
             if(phi >0)
                plot(handles.axes1,x,(Ap/A)^(2/3)*tau,'-b');
             end
             set(handles.axes1,'box','on');
            
             hold on;
             new_tau = 0.21*(A/Ap)^(2/3)*phi^(0.88);
             if (phi >0)
               plot(handles.axes1,phi,(Ap/A)^(2/3)*new_tau,'ro','MarkerFace','r','MarkerSize',7);
               set(handles.axes1,'XScale','log','YScale','log');
             end
            
             comp_tc = get(handles.calp,'Value');
             if (comp_tc == 1)
            
               new_tau = new_tau*tau_c;
               b  = new_tau;
             end
             set(handles.tau,'String',num2str(new_tau));
            
             %plot distribution
             Tw = get(handles.Wall_Temperature,'string');
                 %temperature of wall in K
             Tw = str2double(Tw);
            
             To = get(handles.Initial_Temperature,'string');
                 %initial temperature in K
             To = str2double(To);
            
             %Tw = 578;
             delta_T = Tw - To;
             if (phi>0)
             [TimeT, Temperature, Distance] = predict_dist(Tw,To,delta_T,new_tau,phi,5*new_tau,handles,Uax);
             end
            
             %axis([0 100 0 1000])
             ylabel(handles.axes1,'$(\frac{A_p}{A})^{2/3}\frac{\tau}{\tau_c}$','interpreter', 'latex','FontSize',24);
             xlabel(handles.axes1,'$\phi$','interpreter', 'latex','FontSize',24);
            
             ylabel(handles.axes2,'$T (K)$','interpreter', 'latex','FontSize',20);
             xlabel(handles.axes2,'$t (s)$','FontSize',20,'interpreter','latex');
             if (Uax > 0)
               xlabel(handles.axes2,'$l (m)$','FontSize',20,'interpreter','latex');
             end
            
            if(phi>0)
                if (Uax == 0 || isempty(str2num(get(handles.Velocity,'String'))))
                    column_name = {'t(s)';'Tmin(K)';'Tave(K)';'Tmax(K)'};
                    data_temp = [TimeT;Temperature];
                    data_temp = data_temp';
                    %exist('table_pop')
                    %if (exist('table_pop'))
                    if (ishandle(table_pop))
                      t = uitable(table_pop,'Data',data_temp,'units','Normalized','Position',[0.1 0.1 0.9 0.9],...
                          'ColumnName',column_name);
                      set(table_pop,'MenuBar','none');
                      set(table_pop,'units','Normalized','Position',[0.65 0.1 0.33 0.7])
                      set(table_pop,'Name','Particle Temperature','NumberTitle','off')
                    end
                    %if (~exist('table_pop'))
                    if (~ishandle(table_pop) )
                       table_pop = figure;
                       t = uitable(table_pop,'Data',data_temp,'units','Normalized','Position',[0.1 0.1 0.9 0.9],...
                           'ColumnName',column_name);
                       set(table_pop,'MenuBar','none');
                       set(table_pop,'units','Normalized','Position',[0.65 0.1 0.33 0.7])
                       set(table_pop,'Name','Particle Temperature','NumberTitle','off')
                    end
                elseif(Uax>0)
                    %Distance = Uax*TimeT;
                    column_name = {'t(s)';'x(m)';'Tmin(K)';'Tave(K)';'Tmax(K)'};
                    data_temp = [TimeT;Distance;Temperature];
                    data_temp = data_temp';
                    %exist('table_pop')
                    %if (exist('table_pop'))
                    if (ishandle(table_pop))
                      t = uitable(table_pop,'Data',data_temp,'units','Normalized','Position',[0.1 0.1 0.9 0.9],...
                          'ColumnName',column_name);
                      set(table_pop,'MenuBar','none');
                      set(table_pop,'units','Normalized','Position',[0.65 0.1 0.33 0.7])
                      set(table_pop,'Name','Particle Temperature','NumberTitle','off')
                    end
                    %if (~exist('table_pop'))
                    if (~ishandle(table_pop))
                       table_pop = figure;
                       t = uitable(table_pop,'Data',data_temp,'units','Normalized','Position',[0.1 0.1 0.9 0.9],...
                           'ColumnName',column_name);
                       set(table_pop,'MenuBar','none');
                       set(table_pop,'units','Normalized','Position',[0.65 0.1 0.33 0.7])
                       set(table_pop,'Name','Particle Temperature','NumberTitle','off')
                    end
                end
            
            end
        end
        
        function listbox1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function tau_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function scaling_withvelocity_generatedata1_OpeningFcn(app, varargin)
            % --- Executes just before scaling_withvelocity_generatedata1 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            handles.output = hObject;
            guidata(hObject, handles);
            set ( gcf, 'Color', [0.94 0.94 0.94] )
            
            global table_pop
            table_pop = figure;
            close(table_pop)
            
            global figure_profile;
            figure_profile = figure;
            close(figure_profile);
            
            global figure_Ttime
            figure_Ttime = figure;
            close(figure_Ttime)
           
        end

        % Value changed function: A
        function A_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: DrumDiameter
        function DrumDiameter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: Poissons
        function Poissons_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: VelocityChoice
        function VelocityChoice_Callback(app, event)
            % --- Executes on selection change in VelocityChoice.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
            str = get(hObject, 'String');
            val = get(hObject,'Value');
        end

        % Value changed function: Velocity
        function Velocity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: YoungsModulus
        function YoungsModulus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: bulk_density
        function bulk_density_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: calc_phi
        function calc_phi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: calciner_length
        function calciner_length_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: calp
        function calp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Button pushed function: clear_figure
        function clear_figure_Callback(app, event)
            % --- Executes on button press in clear_figure.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clear_figure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla(handles.axes1)
            cla(handles.axes2)
        end

        % Value changed function: conductivity
        function conductivity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: density
        function density_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: feed_rate
        function feed_rate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: fill_level
        function fill_level_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            check_conditions(handles);
            
            if (get(handles.calp,'Value') ==1)
                FL = get(handles.fill_level,'string');
                FL = str2double(FL);
            
                theta = 4.0*(FL/100)^0.38;
                theta = theta*180/pi;
                set(handles.theta,'String',num2str(theta))
                set(handles.fill_level,'BackgroundColor','w')
                set(handles.theta,'BackgroundColor','y')
            end
        end

        % Value changed function: friction
        function friction_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: heatCapacity
        function heatCapacity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Value changed function: incline
        function incline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
             UpdateAxis(app, handles);
        end

        % Value changed function: theta
        function theta_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            check_conditions(handles);
            
            if (get(handles.calp,'Value') ==1)
                theta = get(handles.theta,'string');
                     %theta in radians%
                theta = str2double(theta);
                theta = theta/180.0*pi;
                FL = (theta-sin(theta))/(2*pi)*100; %0.25*theta^(1/0.38)
                set(handles.fill_level,'String',num2str(FL))
                set(handles.fill_level,'BackgroundColor','y')
                set(handles.theta,'BackgroundColor','w')
            end
        end

        % Value changed function: w
        function w_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            check_conditions(handles);
        end

        % Callback function
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            datacursormode(app.figure1, char(state));
        end

        % Callback function
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.figure1, char(state));
        end

        % Callback function
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            AoR = app.AngleofReposeEditField.Value;
            L = app.PositioninTubemEditField.Value;
            f = 1;
            incline = app.AngleofInclinedegEditField.Value;
            D = app.DrumDiametermEditField.Value;
            w = app.RotationRateRPMEditField.Value;
            bulk_density = app.BulkDensitykgmEditField.Value;
            feed_rate = app.FeedRatekghrEditField.Value;
            %sullivanEqn = (1.77*(AoR)^(0.5)*L*f)/(incline*D*w);
            Uax = (incline*w*D/(1.77*sqrt(AoR)))/60;
            app.umsEditField.Value = Uax;
            feed_rate_v = feed_rate/3600/bulk_density;
            FL = (feed_rate_v/(Uax*D^2*pi/4))*100;
            %fill_level = (7.08*(AoR)^(0.5)*f*feed_rate)/(incline*(D^3)*w*bulk_density*pi());
            app.FillLevelEditField.Value = FL;
            sullivanEqn = L/Uax;
            app.TimeatPositionEditField.Value = double(sullivanEqn);
            app.FillAngleEditField.Value = (4.0*(FL/100)^0.38)*180/pi();

            % Calculate Tau
            T = app.TempPositionKEditField.Value;
            Ti = app.InitialTemperatureKEditField.Value;
            Tw = app.WallTemperatureKEditField.Value;
            t = app.TimeatPositionEditField.Value;
            tau = fsolve(@(tau) log((Tw - T)/(Tw - Ti)) + (t/tau),t)
            app.tEditField.Value = double(tau);

            % Plotting on Ln(T)
            ypoint = log((Tw - T)/(Tw - Ti));
            slope = (ypoint)/t;
            yendpoint = log(0.05);
            xendpoint = yendpoint/slope;
            ytaupoint = tau*slope;
            time = linspace(0,xendpoint,40);
            yline = time*slope;
            cla(app.UIAxes);
            plot(app.UIAxes,time,yline,Color='black');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,t,ypoint,"Marker","o",MarkerFaceColor='magenta',MarkerEdgeColor='magenta');
            if app.ShowTaupointCheckBox.Value == 1
                plot(app.UIAxes,tau,ytaupoint,'o',LineWidth=2,Color='blue');
            end
            hold(app.UIAxes,'off');
                 

            % Calculate other parameters and Tau_p
            Particle_diameter = app.ParticleDiametermmEditField.Value;
            app.ApmmEditField.Value = ((Particle_diameter/1000)^2/4*pi())*1000000;
            app.AmEditField.Value = 0.5*(app.DrumDiametermEditField.Value/2)^2*((app.FillAngleEditField.Value/180*pi()) - sin(app.FillAngleEditField.Value/180*pi()));
            app.tEditField_2.Value = (app.FillAngleEditField.Value/180*pi())/(app.RotationRateRPMEditField.Value*2*pi()/60);
            app.Tau_pEditField.Value = fsolve(@(tau_p) (app.tEditField.Value/app.tEditField_2.Value) - (0.21*(app.AmEditField.Value/(app.ApmmEditField.Value/1000000))^(2/3)*(tau_p/app.tEditField_2.Value)^(0.88)),10);
            %tau_p = solve(scaleupequ,tau_p);
            %app.Tau_pEditField.Value = double(tau_p);
            %app.Tau_pEditField.Value = ((app.tEditField.Value/app.tEditField_2.Value)/0.21*(app.ApmmEditField.Value/app.AmEditField.Value)^(2/3)*app.tEditField_2.Value^(0.88))^(1/0.88);

            % Plotting Temp vs L(m)
            bin_size = 5;
            Tmax = 5*tau;
            count_t = 0;
            phi = app.Tau_pEditField.Value/app.tEditField_2.Value;

            for t = 0.000:Tmax/100:Tmax
                count_t = count_t +1;
                Tave = Tw - exp(-t/tau)*(Tw-Ti);
                std_T = 42*phi^(-1/3)*exp(-t/tau);

                B = Tave + sqrt(3)*std_T;
                A = 2*Tave - B;

                A = A - bin_size/2;
                if ( A<Ti);
                    A = Ti;
                end
                B = B + bin_size/2.0;
                if ( B >Tw);
                    B = Tw;
                end
                TimeT(count_t) = t;
                Distance(count_t) = t*Uax;
                Temperature(1:3,count_t) = [A, Tave, B];
            end

            cla(app.UIAxes_2)
            hold on;
            plot(app.UIAxes_2,Distance,Temperature(2,:),'-k');
            if app.ShowTminandTmaxCheckBox.Value == 1;
                plot(app.UIAxes_2,Distance,Temperature(1,:),'-b');
                plot(app.UIAxes_2,Distance,Temperature(3,:),'-r');
            end

            plot(app.UIAxes_2,L,T,"Marker","o",MarkerFaceColor='magenta',MarkerEdgeColor='magenta');
            set(app.UIAxes_2,'box','on');
        end

        % Button pushed function: CopyValuesFromBTEButton
        function CopyInputValuestoScaleupTabButtonPushed(app, event)
            app.DrumDiameter.Value = string(app.DrumDiametermEditField.Value);
            app.tau_p.Value = string(app.Tau_pEditField.Value);
            app.feed_rate.Value = string(app.FeedRatekghrEditField.Value);
            app.incline.Value = string(app.AngleofInclinedegEditField.Value);
            app.w.Value = string(app.RotationRateRPMEditField.Value);
            app.bulk_density.Value = string(app.BulkDensitykgmEditField.Value);
            app.friction.Value = string(app.AngleofReposeEditField.Value);
            app.Initial_Temperature.Value = string(app.InitialTemperatureKEditField.Value);
            app.Wall_Temperature.Value = string(app.WallTemperatureKEditField.Value);
            app.particle_d.Value = string(app.ParticleDiametermmEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'scale up in rotary calciner - conduction (surc v 1.1)';
            app.figure1.Tag = 'figure1';
            app.TabGroup = uitabgroup(app.figure1);
            app.BedTempertureEntryBTETab = uitab(app.TabGroup);
            app.BedTempertureEntryBTETab.Title = 'Bed Temperture Entry (BTE)';
            app.UIAxes = uiaxes(app.BedTempertureEntryBTETab);
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Ln(T*)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.BedTempertureEntryBTETab);
            xlabel(app.UIAxes_2, 'length (m)')
            ylabel(app.UIAxes_2, 'Temp (K)')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_6 = uipanel(app.BedTempertureEntryBTETab);
            app.ExperimentalInputLabel = uilabel(app.Panel_6);
            app.ExperimentalInputLabel.Text = 'Experimental Input';
            app.Panel_5 = uipanel(app.BedTempertureEntryBTETab);
            app.MaterialPropertiesLabel = uilabel(app.Panel_5);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.Panel_4 = uipanel(app.BedTempertureEntryBTETab);
            app.OperatingConditionsLabel = uilabel(app.Panel_4);
            app.OperatingConditionsLabel.Text = 'Operating Conditions';
            app.CalculateButton = uibutton(app.BedTempertureEntryBTETab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate     ';
            app.InitialTemperatureKEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.InitialTemperatureKEditFieldLabel.Text = 'Initial Temperature (K)';
            app.InitialTemperatureKEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.WallTemperatureKEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.WallTemperatureKEditFieldLabel.Text = 'Wall Temperature (K)';
            app.WallTemperatureKEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.TempPositionKEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.TempPositionKEditFieldLabel.Text = ' Temp @ Position (K)';
            app.TempPositionKEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.RotationRateRPMEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.RotationRateRPMEditFieldLabel.Text = ' Rotation Rate (RPM)';
            app.RotationRateRPMEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.AngleofInclinedegEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.AngleofInclinedegEditFieldLabel.Text = 'Angle of Incline (deg)';
            app.AngleofInclinedegEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.tEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.tEditField.AllowEmpty = 'on';
            app.DrumDiametermEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.DrumDiametermEditFieldLabel.Text = 'Drum Diameter (m)';
            app.tEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.tEditFieldLabel.Text = 't';
            app.DrumDiametermEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.FeedRatekghrEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.FeedRatekghrEditFieldLabel.Text = 'Feed Rate (kg/hr)';
            app.FeedRatekghrEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.AngleofReposeEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.AngleofReposeEditFieldLabel.Text = 'Angle of Repose (°)';
            app.AngleofReposeEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.PositioninTubemEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.PositioninTubemEditFieldLabel.Text = 'Position in Tube (m)';
            app.PositioninTubemEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.TimeatPositionEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.TimeatPositionEditFieldLabel.Text = 'Time at Position';
            app.TimeatPositionEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.DensitykgmLabel = uilabel(app.BedTempertureEntryBTETab);
            app.DensitykgmLabel.Text = 'Bulk Density (kg/m  )';
            app.BulkDensitykgmEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.ParticleDiametermmEditFieldLabel = uilabel(app.BedTempertureEntryBTETab);
            app.ParticleDiametermmEditFieldLabel.Text = 'Particle Diameter (mm)';
            app.ParticleDiametermmEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.Tau_pEditField = uieditfield(app.BedTempertureEntryBTETab, 'numeric');
            app.Tau_pEditField.AllowEmpty = 'on';
            app.OtherCalculatedParametersPanel = uipanel(app.BedTempertureEntryBTETab);
            app.OtherCalculatedParametersPanel.Title = 'Other Calculated Parameters:';
            app.AmLabel = uilabel(app.OtherCalculatedParametersPanel);
            app.AmLabel.Text = 'A (m  )';
            app.AmEditField = uieditfield(app.OtherCalculatedParametersPanel, 'numeric');
            app.AmEditField.ValueDisplayFormat = '%.4f';
            app.AmEditField.AllowEmpty = 'on';
            app.ApmmLabel = uilabel(app.OtherCalculatedParametersPanel);
            app.ApmmLabel.Text = 'Ap (mm  )';
            app.ApmmEditField = uieditfield(app.OtherCalculatedParametersPanel, 'numeric');
            app.ApmmEditField.ValueDisplayFormat = '%.4f';
            app.ApmmEditField.AllowEmpty = 'on';
            app.tEditField_2Label = uilabel(app.OtherCalculatedParametersPanel);
            app.tEditField_2Label.Text = 't';
            app.tEditField_2 = uieditfield(app.OtherCalculatedParametersPanel, 'numeric');
            app.tEditField_2.AllowEmpty = 'on';
            app.FillAngleEditFieldLabel = uilabel(app.OtherCalculatedParametersPanel);
            app.FillAngleEditFieldLabel.Text = 'Fill Angle ';
            app.FillAngleEditField = uieditfield(app.OtherCalculatedParametersPanel, 'numeric');
            app.FillAngleEditField.ValueDisplayFormat = '%.3f';
            app.FillAngleEditField.AllowEmpty = 'on';
            app.FillLevelLabel = uilabel(app.OtherCalculatedParametersPanel);
            app.FillLevelLabel.Text = 'Fill Level (%)';
            app.FillLevelEditField = uieditfield(app.OtherCalculatedParametersPanel, 'numeric');
            app.FillLevelEditField.ValueDisplayFormat = '%.3f';
            app.FillLevelEditField.AllowEmpty = 'on';
            app.umsEditFieldLabel = uilabel(app.OtherCalculatedParametersPanel);
            app.umsEditFieldLabel.Text = 'u (m/s)';
            app.umsEditField = uieditfield(app.OtherCalculatedParametersPanel, 'numeric');
            app.umsEditField.ValueDisplayFormat = '%.4f';
            app.umsEditField.AllowEmpty = 'on';
            app.text01_3 = uilabel(app.OtherCalculatedParametersPanel);
            app.text01_3.Text = '2';
            app.text01_4 = uilabel(app.OtherCalculatedParametersPanel);
            app.text01_4.Text = '2';
            app.text1_15 = uilabel(app.BedTempertureEntryBTETab);
            app.text1_15.Text = 't';
            app.text1_16 = uilabel(app.BedTempertureEntryBTETab);
            app.text1_16.Text = 'p';
            app.text1_17 = uilabel(app.BedTempertureEntryBTETab);
            app.text1_17.Text = 't';
            app.text1_18 = uilabel(app.BedTempertureEntryBTETab);
            app.text1_18.Text = 'p';
            app.text2_10 = uilabel(app.BedTempertureEntryBTETab);
            app.text2_10.Text = '3';
            app.text4_12 = uilabel(app.BedTempertureEntryBTETab);
            app.text4_12.Text = '(Mass Flow)';
            app.text1_19 = uilabel(app.BedTempertureEntryBTETab);
            app.text1_19.Text = 'c';
            app.ShowTaupointCheckBox = uicheckbox(app.BedTempertureEntryBTETab);
            app.ShowTaupointCheckBox.Text = 'Show Tau point';
            app.ShowTminandTmaxCheckBox = uicheckbox(app.BedTempertureEntryBTETab);
            app.ShowTminandTmaxCheckBox.Text = 'Show Tmin and Tmax';
            app.ScaleupofRotaryCalcinerSURCTab = uitab(app.TabGroup);
            app.ScaleupofRotaryCalcinerSURCTab.Title = 'Scale-up of Rotary Calciner (SURC)';
            app.axes2 = uiaxes(app.ScaleupofRotaryCalcinerSURCTab);
            app.axes2.Tag = 'axes2';
            app.axes1 = uiaxes(app.ScaleupofRotaryCalcinerSURCTab);
            app.axes1.Tag = 'axes1';
            app.Panel_3 = uipanel(app.ScaleupofRotaryCalcinerSURCTab);
            app.Panel_7 = uipanel(app.Panel_3);
            app.DispersionChoice = uidropdown(app.Panel_7);
            app.DispersionChoice.Items = {'Ignore Dispersion', 'Input Dispersion Value', 'Compute Dispersion', ''};
            app.DispersionChoice.Tag = 'DispersionChoice';
            app.DispersionChoice.Value = 'Ignore Dispersion';
            app.text39 = uilabel(app.Panel_7);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Dax';
            app.dispersion = uieditfield(app.Panel_7, 'text');
            app.dispersion.Tag = 'dispersion';
            app.dispersion.Value = '0';
            app.text40 = uilabel(app.Panel_7);
            app.text40.Tag = 'text40';
            app.text40.Text = 'm/s';
            app.text0_12 = uilabel(app.Panel_7);
            app.text0_12.Text = '2';
            app.Panel_2 = uipanel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_11 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_11.Tag = 'text4_11';
            app.text4_11.Text = '3';
            app.clear_figure = uibutton(app.ScaleupofRotaryCalcinerSURCTab, 'push');
            app.clear_figure.ButtonPushedFcn = createCallbackFcn(app, @clear_figure_Callback, true);
            app.clear_figure.Tag = 'clear_figure';
            app.clear_figure.Text = 'Clear Figure';
            app.text3_1 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text3_1.Tag = 'text3_1';
            app.text3_1.Text = 'u';
            app.text4_10 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_10.Tag = 'text4_10';
            app.text4_10.Text = 'kg/hr';
            app.feed_rate = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.feed_rate.ValueChangedFcn = createCallbackFcn(app, @feed_rate_Callback, true);
            app.feed_rate.Tag = 'feed_rate';
            app.feed_rate.Value = '0';
            app.text4_9 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_9.Tag = 'text4_9';
            app.text4_9.Text = 'Feed Rate';
            app.text4_8 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_8.Tag = 'text4_8';
            app.text4_8.Text = '(°)';
            app.text4_7 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_7.Tag = 'text4_7';
            app.text4_7.Text = '(°)';
            app.incline = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.incline.ValueChangedFcn = createCallbackFcn(app, @incline_Callback, true);
            app.incline.Tag = 'incline';
            app.incline.Value = '0';
            app.text4_6 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_6.Tag = 'text4_6';
            app.text4_6.Text = 'Incline';
            app.calciner_length = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.calciner_length.ValueChangedFcn = createCallbackFcn(app, @calciner_length_Callback, true);
            app.calciner_length.Tag = 'calciner_length';
            app.calciner_length.Value = '0';
            app.text5_1 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text5_1.Tag = 'text5_1';
            app.text5_1.Text = 'm';
            app.text5 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Length';
            app.text4_5 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_5.Tag = 'text4_5';
            app.text4_5.Text = 'AoR';
            app.friction = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.friction.ValueChangedFcn = createCallbackFcn(app, @friction_Callback, true);
            app.friction.Tag = 'friction';
            app.friction.Value = '0';
            app.text4_4 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_4.Tag = 'text4_4';
            app.text4_4.Text = 'kg/m';
            app.text4_3 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_3.Tag = 'text4_3';
            app.text4_3.Text = 'b';
            app.text4_2 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_2.Tag = 'text4_2';
            app.text4_2.Text = 'r';
            app.bulk_density = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.bulk_density.ValueChangedFcn = createCallbackFcn(app, @bulk_density_Callback, true);
            app.bulk_density.Tag = 'bulk_density';
            app.bulk_density.Value = '0';
            app.text4_1 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4_1.Tag = 'text4_1';
            app.text4_1.Text = '(Mass Flow)';
            app.text4 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Material Properties';
            app.text1_14 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_14.Tag = 'text1_14';
            app.text1_14.Text = '(Heat Transfer)';
            app.text1_13 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_13.Tag = 'text1_13';
            app.text1_13.Text = '(%)';
            app.fill_level = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.fill_level.ValueChangedFcn = createCallbackFcn(app, @fill_level_Callback, true);
            app.fill_level.Tag = 'fill_level';
            app.fill_level.Value = '0';
            app.text1_12 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_12.Tag = 'text1_12';
            app.text1_12.Text = 'Fill Level';
            app.text2_9 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_9.Tag = 'text2_9';
            app.text2_9.Text = 'J/kg/K';
            app.text2_8 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_8.Tag = 'text2_8';
            app.text2_8.Text = 'W/m/K';
            app.text2_7 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_7.Tag = 'text2_7';
            app.text2_7.Text = 'kg/m';
            app.text2_6 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_6.Tag = 'text2_6';
            app.text2_6.Text = '3';
            app.text3 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text3.Tag = 'text3';
            app.text3.Text = 'm/s';
            app.Velocity = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.Velocity.ValueChangedFcn = createCallbackFcn(app, @Velocity_Callback, true);
            app.Velocity.Tag = 'Velocity';
            app.Velocity.Value = '0';
            app.VelocityChoice = uidropdown(app.ScaleupofRotaryCalcinerSURCTab);
            app.VelocityChoice.Items = {'Ignore Flow', 'Plug Flow (input u)', 'Sullivan Model'};
            app.VelocityChoice.ValueChangedFcn = createCallbackFcn(app, @VelocityChoice_Callback, true);
            app.VelocityChoice.Tag = 'VelocityChoice';
            app.VelocityChoice.Value = 'Ignore Flow';
            app.text1_11 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_11.Tag = 'text1_11';
            app.text1_11.Text = 's';
            app.text1_10 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_10.Tag = 'text1_10';
            app.text1_10.Text = 's';
            app.text2_5 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_5.Tag = 'text2_5';
            app.text2_5.Text = 'GPa';
            app.tau_p = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.tau_p.Tag = 'tau_p';
            app.tau_p.Value = '1';
            app.tau_c = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.tau_c.Tag = 'tau_c';
            app.tau_c.Value = '1';
            app.text1_9 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_9.Tag = 'text1_9';
            app.text1_9.Text = 't';
            app.text1_8 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_8.Tag = 'text1_8';
            app.text1_8.Text = 'p';
            app.text2_4 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_4.Tag = 'text2_4';
            app.text2_4.Text = 'k';
            app.text2_3 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_3.Tag = 'text2_3';
            app.text2_3.Text = 'n';
            app.Poissons = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.Poissons.ValueChangedFcn = createCallbackFcn(app, @Poissons_Callback, true);
            app.Poissons.Tag = 'Poissons';
            app.Poissons.Value = '0.25';
            app.text2_2 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_2.Tag = 'text2_2';
            app.text2_2.Text = 'E';
            app.YoungsModulus = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.YoungsModulus.ValueChangedFcn = createCallbackFcn(app, @YoungsModulus_Callback, true);
            app.YoungsModulus.Tag = 'YoungsModulus';
            app.YoungsModulus.Value = '10';
            app.text2_1 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2_1.Tag = 'text2_1';
            app.text2_1.Text = 'Cp';
            app.heatCapacity = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.heatCapacity.ValueChangedFcn = createCallbackFcn(app, @heatCapacity_Callback, true);
            app.heatCapacity.Tag = 'heatCapacity';
            app.heatCapacity.Value = '1';
            app.conductivity = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.conductivity.ValueChangedFcn = createCallbackFcn(app, @conductivity_Callback, true);
            app.conductivity.Tag = 'conductivity';
            app.conductivity.Value = '1';
            app.text2 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text2.Tag = 'text2';
            app.text2.Text = 'r';
            app.density = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.density.ValueChangedFcn = createCallbackFcn(app, @density_Callback, true);
            app.density.Tag = 'density';
            app.density.Value = '1';
            app.text1_7 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_7.Tag = 'text1_7';
            app.text1_7.Text = 'c';
            app.text1_6 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_6.Tag = 'text1_6';
            app.text1_6.Text = 't';
            app.text1_5 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_5.Tag = 'text1_5';
            app.text1_5.Text = 'm';
            app.calc_phi = uicheckbox(app.ScaleupofRotaryCalcinerSURCTab);
            app.calc_phi.ValueChangedFcn = createCallbackFcn(app, @calc_phi_Callback, true);
            app.calc_phi.Tag = 'calc_phi';
            app.calc_phi.Text = 'Material Properties';
            app.DrumDiameter = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.DrumDiameter.ValueChangedFcn = createCallbackFcn(app, @DrumDiameter_Callback, true);
            app.DrumDiameter.Tag = 'DrumDiameter';
            app.DrumDiameter.Value = '1';
            app.text1_4 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_4.Tag = 'text1_4';
            app.text1_4.Text = 'D';
            app.text1_3 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_3.Tag = 'text1_3';
            app.text1_3.Text = '(°)';
            app.text1_2 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_2.Tag = 'text1_2';
            app.text1_2.Text = 'rpm';
            app.text1_1 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1_1.Tag = 'text1_1';
            app.text1_1.Text = 'q';
            app.theta = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.theta.ValueChangedFcn = createCallbackFcn(app, @theta_Callback, true);
            app.theta.Tag = 'theta';
            app.theta.Value = '1';
            app.text1 = uilabel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text1.Tag = 'text1';
            app.text1.Text = 'w';
            app.w = uieditfield(app.ScaleupofRotaryCalcinerSURCTab, 'text');
            app.w.ValueChangedFcn = createCallbackFcn(app, @w_Callback, true);
            app.w.Tag = 'w';
            app.w.Value = '1';
            app.calp = uicheckbox(app.ScaleupofRotaryCalcinerSURCTab);
            app.calp.ValueChangedFcn = createCallbackFcn(app, @calp_Callback, true);
            app.calp.Tag = 'calp';
            app.calp.Text = 'Operating Conditions';
            app.Panel = uipanel(app.ScaleupofRotaryCalcinerSURCTab);
            app.text0_9 = uilabel(app.Panel);
            app.text0_9.Tag = 'text0_9';
            app.text0_9.Text = 'K';
            app.text0_8 = uilabel(app.Panel);
            app.text0_8.Tag = 'text0_8';
            app.text0_8.Text = 'K';
            app.Initial_Temperature = uieditfield(app.Panel, 'text');
            app.Initial_Temperature.Tag = 'Initial_Temperature';
            app.Initial_Temperature.Value = '0';
            app.Wall_Temperature = uieditfield(app.Panel, 'text');
            app.Wall_Temperature.Tag = 'Wall_Temperature';
            app.Wall_Temperature.Value = '578';
            app.text0_5 = uilabel(app.Panel);
            app.text0_5.Tag = 'text0_5';
            app.text0_5.Text = 'mm';
            app.text0_4 = uilabel(app.Panel);
            app.text0_4.Tag = 'text0_4';
            app.text0_4.Text = 's';
            app.particle_d = uieditfield(app.Panel, 'text');
            app.particle_d.Tag = 'particle_d';
            app.particle_d.Value = '1';
            app.tau = uieditfield(app.Panel, 'text');
            app.tau.Tag = 'tau';
            app.tau.Value = '1';
            app.Ap = uieditfield(app.Panel, 'text');
            app.Ap.Tag = 'Ap';
            app.A = uieditfield(app.Panel, 'text');
            app.A.ValueChangedFcn = createCallbackFcn(app, @A_Callback, true);
            app.A.Tag = 'A';
            app.A.Value = '1';
            app.phi = uieditfield(app.Panel, 'text');
            app.phi.Tag = 'phi';
            app.phi.Value = '1';
            app.pushbutton1 = uibutton(app.Panel, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Compute';
            app.text01_2 = uilabel(app.Panel);
            app.text01_2.Tag = 'text01_2';
            app.text01_2.Text = 'm';
            app.text01_1 = uilabel(app.Panel);
            app.text01_1.Tag = 'text01_1';
            app.text01_1.Text = '2';
            app.text0_7 = uilabel(app.Panel);
            app.text0_7.Tag = 'text0_7';
            app.text0_7.Text = 'To';
            app.text0_6 = uilabel(app.Panel);
            app.text0_6.Tag = 'text0_6';
            app.text0_6.Text = 'Tw';
            app.text0_3 = uilabel(app.Panel);
            app.text0_3.Tag = 'text0_3';
            app.text0_3.Text = 'd';
            app.text0_1 = uilabel(app.Panel);
            app.text0_1.Tag = 'text0_1';
            app.text0_1.Text = 'Ap';
            app.text01 = uilabel(app.Panel);
            app.text01.Tag = 'text01';
            app.text01.Text = 'A';
            app.text0 = uilabel(app.Panel);
            app.text0.Tag = 'text0';
            app.text0.Text = 'f';
            app.text0_2 = uilabel(app.Panel);
            app.text0_2.Tag = 'text0_2';
            app.text0_2.Text = 't';
            app.text0_11 = uilabel(app.Panel);
            app.text0_11.Tag = 'text0_11';
            app.text0_11.Text = 'mm';
            app.text0_10 = uilabel(app.Panel);
            app.text0_10.Tag = 'text0_10';
            app.text0_10.Text = '2';
            app.CopyValuesFromBTEButton = uibutton(app.ScaleupofRotaryCalcinerSURCTab, 'push');
            app.CopyValuesFromBTEButton.ButtonPushedFcn = createCallbackFcn(app, @CopyInputValuestoScaleupTabButtonPushed, true);
            app.CopyValuesFromBTEButton.Text = 'Copy Values From BTE';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BTE_and_SURC_Test(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)scaling_withvelocity_generatedata1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end